classdef Project_Game < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        homeTab                 matlab.ui.container.Tab
        VolumeSlider            matlab.ui.control.Slider
        VolumeSliderLabel       matlab.ui.control.Label
        Game_Name               matlab.ui.control.Label
        EndlessModeSwitch       matlab.ui.control.Switch
        EndlessModeSwitchLabel  matlab.ui.control.Label
        Switch                  matlab.ui.control.Switch
        UITable                 matlab.ui.control.Table
        Image                   matlab.ui.control.Image
        NameInput               matlab.ui.control.TextArea
        nextBackground          matlab.ui.control.Button
        prevBackground          matlab.ui.control.Button
        Select_backgroundImg    matlab.ui.control.Image
        StartGameButton         matlab.ui.control.Button
        ControlsTab             matlab.ui.container.Tab
        LEFTCLICKLabel          matlab.ui.control.Label
        TELEPORTLabel           matlab.ui.control.Label
        DLabel                  matlab.ui.control.Label
        ALabel                  matlab.ui.control.Label
        RIGHTLabel              matlab.ui.control.Label
        LEFTLabel               matlab.ui.control.Label
        SPACELabel              matlab.ui.control.Label
        JUMPDOUBLEJUMPLabel     matlab.ui.control.Label
        CreditsTab              matlab.ui.container.Tab
        suno_ai_Link            matlab.ui.control.Hyperlink
        BackgroundMusicLabel    matlab.ui.control.Label
        Hyperlink3              matlab.ui.control.Hyperlink
        Hyperlink2              matlab.ui.control.Hyperlink
        PIXELARTLabel           matlab.ui.control.Label
        Hyperlink               matlab.ui.control.Hyperlink
        Health_bar              matlab.ui.control.Label
        hp_text                 matlab.ui.control.Label
        TeleportLabel           matlab.ui.control.Label
        levelLabel              matlab.ui.control.Label
        pause_Image_2           matlab.ui.control.Image
        pause_Image             matlab.ui.control.Image
        Timer                   matlab.ui.control.Label
        GB_11                   matlab.ui.control.Image
        GB_10                   matlab.ui.control.Image
        GB_9                    matlab.ui.control.Image
        GB_8                    matlab.ui.control.Image
        GB_7                    matlab.ui.control.Image
        GB_6                    matlab.ui.control.Image
        GB_5                    matlab.ui.control.Image
        GB_4                    matlab.ui.control.Image
        GB_3                    matlab.ui.control.Image
        GB_2                    matlab.ui.control.Image
        GB_1                    matlab.ui.control.Image
        Background_image        matlab.ui.control.Image
    end

    properties (Access = public)
      ComponentHandles    cell
      playerSprite_run    cell
      playerSprite_run_inv  cell
      playerSprite_idle      cell
      playerSprite_idle_inv   cell
      playerSprite_animation cell
      player_1_sprite     matlab.ui.control.Image
      playerSprite_Dim    int32
      player_1_vel        single = [0 0]
      player_1_momt       single = [0 0]
      player_2_vel        single = [0 0]
      player_2_momt       single = [0 0]
      ground_level        single
      countDown_time      matlab.ui.control.Label
      player_1_score      double
      GameOverPanel       matlab.ui.container.Panel
      return_mainMenu     matlab.ui.control.Button
      numImages           int16
      run_numImages       int16
      idle_numImages      int16
      inv         = false
      curr_animation
       ButtonGroup       matlab.ui.container.ButtonGroup
       AnswerBtn           matlab.ui.control.Button
       QuestionLabel     matlab.ui.control.Label
       Button4           matlab.ui.control.RadioButton
       Button3           matlab.ui.control.RadioButton
       Button2           matlab.ui.control.RadioButton
       Button            matlab.ui.control.RadioButton
       loadedQuestions
       question_num  int16 =0
       Q_results      matlab.ui.control.Label
       double_jump_unlock = false
       player_jump int16 = 0;
       teleport_pos
       GAME_ON =false
       question_correct=0;
       endless_runner int16 = 0;
       background_image =  "Images/Background/1_Mountain_valley.png";
       background_Img_idx = 1;
       player_name = '';
       
    end
    
    % Enemy sprites
    properties (Access = public)
         enemy_sprite        cell
    end

     %%% Helper functions used a the start of game %%%

    methods (Static)  

        function playerSprite= Configure_sprite(folderPath)


            % Get a list of all files in the folder
            fileList = dir(fullfile(folderPath, "*.png"));
            % disp("FileList:");
            % disp(fileList);
            length = numel(fileList);
            % disp(length);
            image=1;
             % Prellocate array to avoid copying on iteration
            playerSprite = cell(1, length*2);

            % Iterate through the list of files
            for i = 1:length
                % Get the name of the current file
                fileName = fileList(i).name;
                animationImage = fullfile(folderPath + "/"+fileName);
                % disp(animationImage)
                % animationImage = imread(fileName);
                playerSprite{image} = animationImage;
                playerSprite{image+1} = animationImage;
                image=image+2;
                % Display the file name

            end
            % disp(image);
            % disp(length);


        end
        % function playerSprite = Configure_sprite(folderPath)
        %     % Get a list of all PNG files in the folder
        %     fileList = dir(fullfile(folderPath, '*.png'));
        % 
        %     % Preallocate cell array
        %     playerSprite = cell(1, numel(fileList) * 2);
        % 
        %     % Iterate through the list of files
        %     for i = 1:numel(fileList)
        %         % Get the name of the current file
        %         fileName = fileList(i).name;
        % 
        %         % Load image with transparency
        %         [image, alpha] = imread(fullfile(folderPath, fileName));
        % 
        %         % Combine RGB channels with alpha channel
        %         animationImage = cat(3, image, alpha);
        % 
        %         % Store the image in the cell array twice
        %         playerSprite{2*i - 1} = animationImage;
        %         playerSprite{2*i} = animationImage;
        %     end
        % end

        % function update_enemy_sprites(sprite)
        %     % disp(sprite.sprite_image.Position +[sprite.vel 0 0]);
        %     sprite.sprite_image.Position = sprite.sprite_image.Position +[sprite.vel 0 0];
        % 
        % end
        
        function label = game_over_label(GameOverPanel)
            label= uilabel(GameOverPanel);
            label.FontSize = 18;
            label.FontAngle = "italic";
            label.HorizontalAlignment = "center";
            label.VerticalAlignment = "center";

        end

        function label = game_over_info(GameOverPanel)
            label= uilabel(GameOverPanel);
            label.FontSize = 20;
            label.HorizontalAlignment = "center";
            label.VerticalAlignment = "center";
            label.FontWeight = "bold";
            label.FontColor = [0 0 1];
            label.FontName = 'Agency FB';
        end

       
    end

   properties (Access = public, SetObservable)
        level int16= 0;
        teleport = false
   end


    methods (Access = private)

        function updateLevelLabel(app)
            % Update the text of the levelLabel with the current level
            app.levelLabel.Text = ['Level: ' num2str(app.level)];
        end

        function updateTeleportStatus(app)
            switch app.teleport
                case 1
                    app.TeleportLabel.FontColor = [0 1 0];
                otherwise
                    app.TeleportLabel.FontColor = [1 0 0];
            end
        end


    end
    methods (Access = public)
        
        function reset_game(app)
             app.level = 0;
             app.question_num  = 0;
            app.double_jump_unlock = false;
            app.player_jump = 0;
            app.teleport = false;
      
        end

        function pause_countDown(app)
            % Create the countdown Label
            app.countDown_time.HorizontalAlignment = 'center';
            app.countDown_time.FontName = 'Agency FB';
            app.countDown_time.FontSize = 48;  
            app.countDown_time.FontColor=[1 1 1];
            app.countDown_time.FontWeight = "bold";
            height = 25;
            width = 70;
            pos_x = round(app.UIFigure.Position(3)/2 ); 
            pos_y = round(app.UIFigure.Position(4)/2);
            app.countDown_time.Position = [ pos_x pos_y height width];
            app.countDown_time.Visible = "on";
            for i = 1:3
                app.countDown_time.Text = num2str(4-i);
                pause(1);
            end
            app.countDown_time.Visible = "off";

        end
        
        function questionPause(app)
            app.question_num = app.question_num+1;
            question = app.loadedQuestions.question{app.question_num};
            options = app.loadedQuestions.options{app.question_num};

            
            % Create ButtonGroup
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Multiple choice Question';
            app.ButtonGroup.TitlePosition = "centertop";
            app.ButtonGroup.Position = [0 0 app.UIFigure.Position(3) app.UIFigure.Position(4)];
            app.ButtonGroup.BorderWidth = 10;
            app.ButtonGroup.BorderType = "none";
            app.ButtonGroup.BorderColor = [0.72,0.27,1.00];
            app.ButtonGroup.BackgroundColor = [0.94,0.94,0.94] ;

            % Create QuestionLabel
            app.QuestionLabel = uilabel(app.ButtonGroup);
            app.QuestionLabel.Position = [round(app.UIFigure.Position(3)/2)-250 round(4*app.UIFigure.Position(4)/5) 500 50];
            app.QuestionLabel.Text = question;
            app.QuestionLabel.FontWeight="bold";
            app.QuestionLabel.FontSize = 16;

            seperation =round(app.UIFigure.Position(3)/12);
            indent =  round(app.UIFigure.Position(4)/8);
            start = round(app.QuestionLabel.Position(2)*0.95);
            % Create Button
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = options{1};
            app.Button.Position = [indent start-seperation  150 50];
            app.Button.Value = true;

            % Create Button2
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = options{2};
            app.Button2.Position = [indent start-seperation*2 150 50];

            % Create Button3
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = options{3};
            app.Button3.Position = [indent start-seperation*3 150 50];

            % Create Button4
            app.Button4 = uiradiobutton(app.ButtonGroup);
            app.Button4.Text = options{4};
            app.Button4.Position = [indent start-seperation*4 150 50];


            % Create Button5
            app.AnswerBtn = uibutton(app.ButtonGroup, 'push');
            app.AnswerBtn.Position = [app.UIFigure.Position(3)/2-75 50 150 50];
            app.AnswerBtn.Text = 'Submit';
            app.AnswerBtn.BackgroundColor = [0 0 0];
            app.AnswerBtn.FontName = 'Agency FB';
            app.AnswerBtn.FontSize = 20;
            app.AnswerBtn.FontAngle = 'italic';
            app.AnswerBtn.FontColor = [0.502 0.5804 1];
            

            app.AnswerBtn.ButtonPushedFcn = createCallbackFcn(app, @submitAnswer, true);

            % Pause execution until the submit button is pressed
            uiwait(app.UIFigure);
            % Execution resumes here after the submit button is pressed
             % You can now access the selected radio button and continue your processing
        end
        
        % Callback function for the submit button
        function submitAnswer(app,~)
            answer = app.loadedQuestions.correctAnswers{app.question_num};
            
            % Determine which radio button is selected
            if app.Button.Value
                selectedOption = app.Button.Text;
                if selectedOption == answer;app.Button.FontColor = [0.00,0.68,0.07];end
            elseif app.Button2.Value
                selectedOption = app.Button2.Text;
                if selectedOption == answer;app.Button2.FontColor = [0.00,0.68,0.07];end
            elseif app.Button3.Value
                selectedOption = app.Button3.Text;
                if selectedOption == answer;app.Button3.FontColor = [0.00,0.68,0.07];end
            elseif app.Button4.Value
                selectedOption = app.Button4.Text;
                if selectedOption == answer;app.Button4.FontColor = [0.00,0.68,0.07];end
            else
                selectedOption = 'No option selected';
            end
            app.AnswerBtn.Enable="off";
            pause(2);
            % Display the selected option (for demonstration)
            disp(['Selected option: ' selectedOption]);

            app.ButtonGroup.Visible = 'off';
            app.Q_results = uilabel(app.UIFigure);
            pos_x = round(app.UIFigure.Position(3)/2 ); 
            pos_y = round(app.UIFigure.Position(4)/2);
            app.Q_results.Position = [pos_x-75 pos_y 150 150];
            app.Q_results.VerticalAlignment = "center";
            app.Q_results.HorizontalAlignment = 'center';
            app.Q_results.WordWrap="on";
            app.Q_results.FontName = 'Agency FB';
            app.Q_results.FontSize = 24; 
            app.Q_results.BackgroundColor = [1 1 1];
            app.Q_results.FontColor=[0.00,0.40,0.80];
            app.Q_results.FontWeight = "bold";
            app.Q_results.Visible = "on";
            if selectedOption == answer
                disp("correct answer")
                app.player_level_up();
                app.question_correct=app.question_correct+1;
            else
                app.loadedQuestions.correctAnswers{end+1}=app.loadedQuestions.correctAnswers{app.question_num};
                app.loadedQuestions.question{end+1} = app.loadedQuestions.question{app.question_num};
                app.loadedQuestions.options{end+1}= app.loadedQuestions.options{app.question_num};
                disp("wrong answer")
                app.Q_results.Text = "No abilities Gained";

            end
            pause(1.5)
            app.Q_results.Visible = "off";
            % Resume execution
            uiresume(app.UIFigure);
        end

        function player_level_up(app)
            app.level= app.level +1;
            switch  app.level
                case 1
                    app.Q_results.Text = "Jump Ability gained";
                    
                case 2
                    app.Q_results.Text = "Lateral Movement Enabled";
                case 3
                    app.Q_results.Text ="Double jump activated";
                    app.double_jump_unlock = true;
                case 4
                     app.Q_results.Text ="Teleportation Achieved";
                     app.teleport = true;
                     
                otherwise
                    app.Q_results.Text = "Ability refill";
                    app.teleport = true;
            end
        end
        
        function add_sprite(app)
            pos_3 =app.UIFigure.Position(3);
            pos_4  =app.UIFigure.Position(4);
            lvl = app.level;
            gnd_level = int16(app.ground_level);
           sprite=Sprite(gnd_level,uiimage(app.UIFigure),pos_3, lvl,pos_4);
           app.enemy_sprite{end+1}= sprite;

        end

        function gameLoop(app,Soundtracks)

            curr_track = 2;
            soundtracks_len = length(Soundtracks);
            audioplayer = Soundtracks{1};

            app.numImages = app.idle_numImages;
            currentImageIndex = 1; % Start from the first image
            app.GAME_ON= true;
            app.playerSprite_animation = app.playerSprite_idle;
            app.curr_animation = "idle";
            interval = 20; % Initial interval in seconds
            next_prompt_time = 5;

            % Enemy Spawn
            next_enemy_spawn = 4;

            % Start timer
            game_won = false;
            question_time=0;
            alive =true;
            app.question_correct = 0;
             tic;

            while(app.GAME_ON)
                % start=toc;

                % Check if it's time to display the prompt
                if toc >= next_prompt_time
                    if app.question_num+1<=length(app.loadedQuestions.question )
                    Q_start= toc;
                    questionPause(app);
                    
                    % Update the time for the next prompt
                    focus(app.UIFigure);
                    pause_countDown(app);
                    next_prompt_time = toc + interval;
                    % disp(next_prompt_time);
                    question_time = question_time+toc-Q_start;
                    else
                        if app.endless_runner == 0
                            game_won = true;
                            app.GAME_ON = false;
                        end
                        
                        % disp(['App endless runner: ' app.endless_runner])
                        % disp(app.endless_runner ~= false)
                        next_prompt_time = next_prompt_time + 20;
                        app.level= app.level+1;
                        health = min(app.Health_bar.Position(3)+20,100);
                        app.Health_bar.Position(3) = health;
                        app.hp_text.Text = num2str(health);
                        app.teleport = true;
                    end          
                    
                end

                enemy_sprite_alive = true(size(app.enemy_sprite));
                app.player_1_update()
                loop_health=int16(0);
                % Update player_1 position
                app.player_1_sprite.Position(1:2) = app.player_1_sprite.Position(1:2) + app.player_1_vel;
                for i = 1:numel(app.enemy_sprite)
                    sprite = app.enemy_sprite{i};
                     sprite.update_vel();
                     % Update sprite position
                      spritePos= sprite.sprite_image.Position(1:2)+ sprite.vel;
                     sprite.sprite_image.Position(1:2)= spritePos;
                    loop_health = loop_health+sprite.collision(app.player_1_sprite.Position);
                     if spritePos(1) < 0 || spritePos(1) > 1e5 || ~sprite.alive
                        enemy_sprite_alive(i) = false;
                        % % disp("added to exclude list");
                        sprite.delete_sprite();
                     end
                end

                if(loop_health ~= 0)
                    added_health = app.Health_bar.Position(3)+loop_health;
                    if(added_health<1)
                        app.GAME_ON = false;
                        alive = false;
                    else
                        app.Health_bar.Position(3)= added_health;
                        app.hp_text.Text = num2str(added_health);
                    end
                    
                end

                currentImageIndex = app.update_player_animation(app.player_1_vel, currentImageIndex);

                 % Move to the next image index
                 currentImageIndex = mod(currentImageIndex, app.numImages) + 1;
                 app.player_1_sprite.ImageSource = app.playerSprite_animation{currentImageIndex};
                 

                % Create a new array without the excluded element
                 app.enemy_sprite = app.enemy_sprite(enemy_sprite_alive);
                 app.Timer.Text = sprintf('%.3f',toc);

                 if toc>next_enemy_spawn
                    add_sprite(app);
                    % Random time fo rnext spawn 
                    upper_bound = max(0.25, 4.5 - app.level/2);
                    random = rand();
                    next_enemy_spawn=toc + 0.2 + upper_bound*random;
                end
                
                 if audioplayer.Running == "off"
                    disp("next soundtrack")
                    audioplayer =Soundtracks{curr_track};
                    play(audioplayer);
                    curr_track = mod(curr_track, soundtracks_len) + 1;
                end
                 % time= toc - start;
                 % disp(time);
                 pause(0.025)
            end
            
            total_gameTime= toc;
            app.player_1_score= str2double(app.Timer.Text);
            level = app.level;
            for i = 1:numel(app.enemy_sprite)
                 app.enemy_sprite{i}.delete_sprite();     
            end
            app.enemy_sprite={};
            delete(app.player_1_sprite);
            app.reset_game();
            % disp(total_gameTime,question_time,alive)
            app.game_over(total_gameTime,question_time,alive,level);
            
        end

        function player_1_update(app)
            spritePos = app.player_1_sprite.Position;
            spriteVel = app.player_1_vel;
            ground_lvl = app.ground_level;
           % Update Y direction
           % float point precisoin error 5.2643e-04
           if((spritePos(2)-ground_lvl)+spriteVel(2) + app.player_1_momt(2)<-1e-4)
                app.player_1_sprite.Position(2) = app.ground_level;
                app.player_1_momt(2) = 0;
                app.player_1_vel(2) = 0;
                % disp(app.ground_level);
           elseif(spritePos(2)>app.ground_level)
               app.player_1_momt(2) = app.player_1_momt(2)-0.4;
           end
           app.player_1_vel =app.player_1_vel + app.player_1_momt;

           if(spritePos(1)+app.player_1_vel(1)<1)
               app.player_1_vel(1) = 0;
               app.player_1_momt(1) = 0;
               app.player_1_sprite.Position(1) =0;
           elseif((spritePos(1)+app.player_1_vel(1)+spritePos(3))>app.UIFigure.Position(3))
               app.player_1_vel(1) = 0;
               app.player_1_momt(1) = 0;
               app.player_1_sprite.Position(1) =app.UIFigure.Position(3)-spritePos(3);
           end
          
           if spritePos(2)<=ground_lvl && app.double_jump_unlock
               app.player_jump = 0;
           end

        end
        
        function updated_index = update_player_animation(app,vel, index)
            updated_index = index;
            prev_animation = app.curr_animation;
            if (all(abs(vel)< 1e-4))
                    if app.inv
                        app.curr_animation = "idle_inv";
                        
                    else
                        app.curr_animation = "idle";
                        
                    end
                    
            elseif vel(1)==8

                    app.inv = false;
                    app.curr_animation = "run";
                    
      
            elseif vel(1)==-8
                    
                    app.inv = true;
                    app.curr_animation = "run_inv";
                    
            end

            if ~(prev_animation == app.curr_animation)
                updated_index =1;
                switch app.curr_animation
                    case "idle"
                        app.playerSprite_animation = app.playerSprite_idle;
                        app.numImages= app.idle_numImages;
                        
                    case "idle_inv"
                        app.playerSprite_animation= app.playerSprite_idle_inv;
                        app.numImages= app.idle_numImages;
                        
                    case "run"
                        app.playerSprite_animation= app.playerSprite_run;
                        app.numImages = app.run_numImages;
                     
                    case "run_inv"
                        app.playerSprite_animation= app.playerSprite_run_inv;
                        app.numImages = app.run_numImages;
                       
                end
            end
                
        end

        function game_over(app,total_time,question_time,alive,level)
            
            % disp(total_gameTime,question_time,alive)
            survival_time = total_time - question_time;
            Endless= app.endless_runner;

           
             % Create GameOverPanel
            app.GameOverPanel = uipanel(app.UIFigure);
            app.GameOverPanel.BorderColor = [0.502 0.5804 1];
            app.GameOverPanel.ForegroundColor = [0.502 0.5804 1];
            app.GameOverPanel.TitlePosition = 'centertop';
            app.GameOverPanel.Title = 'Game Over';
            app.GameOverPanel.BackgroundColor = [0.78,0.98,0.98];
            app.GameOverPanel.FontName = 'Agency FB';
            app.GameOverPanel.FontWeight = 'bold';
            app.GameOverPanel.FontSize = 24;
            app.GameOverPanel.Position = [0 0 app.UIFigure.Position(3)+4 app.UIFigure.Position(4)+4];
            
            % Game stats

            % Position
            
            total_time_str = num2str(total_time);
            top = 3*app.UIFigure.Position(4)/4;
            bottom = app.UIFigure.Position(4)/5;
            margin = app.UIFigure.Position(3)/10;
            width = 4*app.UIFigure.Position(3)/5;
            seperation = round((top -bottom)/5);


            total_time_label = Project_Game.game_over_label(app.GameOverPanel);
            total_time_label.Position = [margin top width/2 100];
            total_time_label.Text = "Total Time";
            
 
            total_time_info = Project_Game.game_over_info(app.GameOverPanel);
            total_time_info.Text = total_time_str;
            total_time_info.Position = [margin+width/2 top width/2 100];

            time_survived_label= Project_Game.game_over_label(app.GameOverPanel);
            time_survived_label.Position = [margin top-seperation width/2 100];
            time_survived_label.Text = "Time Survived";

            time_survived = Project_Game.game_over_info(app.GameOverPanel);
            time_survived.Position = [margin+width/2 top-seperation width/2 100];
            time_survived.Text = num2str(survival_time);

            Game_mode_label = Project_Game.game_over_label(app.GameOverPanel);
            Game_mode_label.Position = [margin top-seperation*2 width/2 100];
            Game_mode_label.Text = "Game mode";

            Game_mode = Project_Game.game_over_info(app.GameOverPanel);
            Game_mode.Position = [margin+width/2 top-seperation*2 width/2 100];
            disp(["endless runner here: ", Endless]);
            if Endless == 1
                Game_mode.Text = "Endless runner";
            else
                Game_mode.Text = "normal";
            end

            player_status_label = Project_Game.game_over_label(app.GameOverPanel);
            player_status_label.Position = [margin top-seperation*3 width 100];
            if alive
                player_status_label.Text = "You Survived!";
            else
                player_status_label.Text = "You Died";
            end

            game_stats= struct;
            game_stats.total_time = total_time;
            game_stats.survival_time = survival_time;
            game_stats.game_mode = Game_mode.Text;
            game_stats.name = app.player_name;
            game_stats.level = num2str(level);

            app.save_leaderboard(Endless, alive, game_stats)


            app.return_mainMenu = uibutton(app.GameOverPanel, 'push');
            app.return_mainMenu.Text = "Main Menu";
            app.return_mainMenu.BackgroundColor = [0 0 0];
            app.return_mainMenu.FontName = 'Agency FB';
            app.return_mainMenu.FontSize = 20;
            app.return_mainMenu.FontAngle = 'italic';
            app.return_mainMenu.FontColor = [0.502 0.5804 1];
            width = 145;
            height = 65;
            left = int32((app.UIFigure.Position(3)-width)/2);
            bottom = int32(round(app.UIFigure.Position(4)/6));
            app.return_mainMenu.Position = [left bottom width height];
            app.return_mainMenu.ButtonPushedFcn = createCallbackFcn(app, @Back_to_Menu, true);


        end

        
        function save_leaderboard(app,Endless, alive,game_stats)
            disp(['Endless: ', num2str(Endless)]);
            disp(Endless);
            disp(Endless == 1)
            disp(['Alive: ', num2str(alive)]);
            % disp(['game_stats: ', num2str(game_stats)]);

            if Endless == 1
                file_name = 'Endless_Top_5.mat';
                 % Load leaderboard data
                if exist(file_name, 'file') == 2
                    load(file_name); % Load data without assigning it to a variable
                     % Check if 'leaderboard' variable already exists
                     game_stats_array = leaderboard.game_stats_array;
                 else
                        % Create new leaderboard if file does not exist
                        game_stats_array = {};
                 end
            
                    % Add current game stats to game_stats_array
                    game_stats_array{end+1} = game_stats;
                     
                    survival_times = zeros(1, numel(game_stats_array));
                    for i = 1:numel(game_stats_array)
                        survival_times(i) = game_stats_array{i}.survival_time;
                    end
            
                    [sorted_times, idx] = sort(survival_times, 'descend');
                    game_stats_array =game_stats_array(idx);
            
                    % Keep only top 5 entries
                    game_stats_array = game_stats_array(1:min(5, numel(game_stats_array)));
            else
                disp("normal save else")
                if alive
                    file_name = 'Normal_Top_5.mat';
                    if exist(file_name, 'file') == 2
                        load(file_name); % Load data without assigning it to a variable
                         % Check if 'leaderboard' variable already exists
                         game_stats_array = leaderboard.game_stats_array;
                     else
                            % Create new leaderboard if file does not exist
                            game_stats_array = {};
                    end
                     % Add current game stats to game_stats_array
                      game_stats_array{end+1} = game_stats;
                     total_times = zeros(1, numel(game_stats_array));
                    for i = 1:numel(game_stats_array)
                        total_times(i) = game_stats_array{i}.total_time;
                    end
            
                    [sorted_times, idx] = sort(total_times, 'ascend');
                    game_stats_array =game_stats_array(idx);
            
                    % Keep only top 5 entries
                    game_stats_array = game_stats_array(1:min(5, numel(game_stats_array)));
                end
            end

            if exist("game_stats_array", "var")
                % Save updated leaderboard back to file
                leaderboard.game_stats_array = game_stats_array;
                save(file_name, 'leaderboard');
                disp('Leaderboard updated and saved successfully.');
                disp(leaderboard)
            else
                disp("failed to save. game_stats_array false")
            end
        end

        function Back_to_Menu(app, ~)
            app.GameOverPanel.Enable="off";
            app.GameOverPanel.Visible = "off";
            app.TabGroup.Visible = 'on';

        end
        
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartGameButton
        function Start_game(app, event)
            app.Background_image.ImageSource = app.Select_backgroundImg.ImageSource;
            app.TabGroup.Visible = 'off';
            app.countDown_time = uilabel(app.UIFigure);
            background_music = {};
            
            volume = app.VolumeSlider.Value/100;
            volume = volume *0.4;
            disp(["volume: " volume])
           
            % Read audio file
             [audioData, fs] = audioread('Background_music/An Endless Race.mp3'); 
            % Create audio player object
            soundtrack_1 = audioplayer(audioData*volume, fs);
            background_music{end+1} = soundtrack_1;
            
            [audioData, fs] = audioread('Background_music/Flute_background.mp3');
            soundtrack_2 = audioplayer(audioData*volume,fs);
            background_music{end+1} = soundtrack_2;
            
            % Play audio
            play(soundtrack_1);

            % Add listener to app.level property
            addlistener(app, 'level', 'PostSet', @(src, event) updateLevelLabel(app));
            addlistener(app, 'teleport', 'PostSet', @(src, event) updateTeleportStatus(app));
            

            CyborgSprite_paths = {
                % 'Images\Cyborg\Cyborg_run','Images\Cyborg\Cyborg_idle','Images\Cyborg\Cyborg_run_inv','Images\Cyborg\Cyborg_idle_inv',
                'Images/Cyborg/Cyborg_run','Images/Cyborg/Cyborg_idle','Images/Cyborg/Cyborg_run_inv','Images/Cyborg/Cyborg_idle_inv',

                };
           
            app.playerSprite_run = Project_Game.Configure_sprite(CyborgSprite_paths{1});
            app.playerSprite_idle = Project_Game.Configure_sprite(CyborgSprite_paths{2});
            app.playerSprite_run_inv = Project_Game.Configure_sprite(CyborgSprite_paths{3});
            app.playerSprite_idle_inv = Project_Game.Configure_sprite(CyborgSprite_paths{4});


            app.run_numImages = length(app.playerSprite_run);
            app.idle_numImages = length(app.playerSprite_idle);


            app.player_1_sprite = uiimage(app.UIFigure);
            app.ground_level = app.GB_1.Position(2)+ app.GB_1.Position(4);
            app.player_1_sprite.Position = [100 app.ground_level 48 48];
            app.player_1_sprite.ImageSource = app.playerSprite_run{1};
            disp(app.player_1_sprite.Position);
            app.Health_bar.Position(3) = 100;
            app.Timer.Text = '0.000';
            app.hp_text.Text = '100';

            app.loadedQuestions = load('ks3_math_questions.mat').maths;
            disp(app.loadedQuestions);
            % app.Health_bar.
            % Store the handle of the new button
            % app.ComponentHandles{end+1} = newButton;

            disp("pause clicked");
            pause_countDown(app);
            focus(app.UIFigure);
            app.gameLoop(background_music);  
            % app.playerSprite = Configure_sprite();
            
        end

        % Image clicked function: pause_Image
        function Pause_Menu(app, event)

           add_sprite(app)
     
        end

        % Callback function
        function UIFigureKeyPress(app, event)

        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            if app.GAME_ON
            key = event.Key;
            switch key
                case "a"
                    if app.level>1
                    app.player_1_vel(1)=-8;
                    end
                case "d" 
                    if app.level>1
                    app.player_1_vel(1)=8;
                    end
                case "space"
                     if (app.player_1_sprite.Position(2)<=app.ground_level||(app.player_jump<1)) && app.level>0
                        app.player_1_momt(2)=3.2;
                        app.player_1_vel(2)=0;
                        app.player_jump=app.player_jump +1;
                     end
            end
            end
            % disp(key);
        end

        % Callback function
        function UIFigureWindowButtonDown(app, event)

        end

        % Callback function
        function UIFigureKeyPress2(app, event)

        end

        % Window button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
            if app.GAME_ON && app.teleport
                app.player_1_sprite.Position(1) = round(event.Point(1));
                app.player_1_sprite.Position(2) = round(event.Point(2));
                app.teleport = false;
            end  
        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)
            key = event.Key;
            switch key
                case "a"
                    if app.player_1_vel(1) == -8;app.player_1_vel(1)=0;end          
                case "d"
                    if app.player_1_vel(1) == 8; app.player_1_vel(1)=0;end

            end
        end

        % Image clicked function: pause_Image_2
        function add_(app, event)
            % add_sprite(app);
            app.GAME_ON = false;
        end

        % Callback function: Hyperlink, Hyperlink2, Hyperlink3
        function HyperlinkClicked(app, event)
            % disp(event.Source.URL);
            web(event.Source.URL);
        end

        % Value changed function: EndlessModeSwitch
        function EndlessModeSwitchValueChanged(app, event)
            % app.endless_runner =  round(event.ValueIndex -1);
            app.endless_runner = str2num(event.Value);  % for V. 2023
      
           
        end

        % Button pushed function: nextBackground
        function nextBackgroundButtonPushed(app, event)

            fileList = dir(fullfile("Images/Background/", "*.png"));
            Len = length(fileList);
            
            % Increment the index and wrap around if it exceeds Len
            app.background_Img_idx = app.background_Img_idx + 1;
            if app.background_Img_idx > Len
                app.background_Img_idx = 1; % Wrap around to the first index
            end
            
            background = fileList(app.background_Img_idx).name;
            app.Select_backgroundImg.ImageSource = fullfile("Images/Background/", background);

        end

        % Button pushed function: prevBackground
        function prevBackgroundButtonPushed(app, event)
            fileList = dir(fullfile("Images/Background/", "*.png"));
            Len = length(fileList);
            % Decrement the index and wrap around if it becomes zero
            app.background_Img_idx = app.background_Img_idx - 1;
            if app.background_Img_idx < 1
                app.background_Img_idx = Len;
            end
            
            background = fileList(app.background_Img_idx).name;
            app.Select_backgroundImg.ImageSource = fullfile("Images/Background/", background);
        end

        % Value changed function: NameInput
        function NameInputValueChanged(app, event)
            value = event.Value;
            app.player_name= value{1};
        end

        % Callback function
        function UIFigureWindowButtonDown2(app, event)
            
        end

        % Value changed function: Switch
        function leaderBoard_switch(app, event)
            value = app.Switch.Value;
            value=str2num(value);
            if value == 0
                % disp("endless")
                filename = 'Endless_Top_5.mat';
                if exist(filename, 'file')
                top5_data = load('Endless_Top_5.mat');
                % disp(top5_data.leaderboard.game_stats_array{1});
                array = top5_data.leaderboard.game_stats_array;
                tableData = cell(numel(array), 2);
                for i = 1:numel(array)
                    tableData{i, 1} = array{i}.name;
                    tableData{i, 2} = num2str(array{i}.survival_time);
                    tableData{i,3} = array{i}.level;
                end
                app.UITable.Data = tableData;
                app.UITable.ColumnName{2} = "Survival Time";
                end
              
            else
                % a =load('Normal_Top_5.mat');
                % array =a.leaderboard;
                filename = 'Normal_Top_5.mat';
                if exist(filename, 'file')
                top5_data = load('Normal_Top_5.mat');
                % disp(top5_data.leaderboard.game_stats_array{1});
                array = top5_data.leaderboard.game_stats_array;
                tableData = cell(numel(array), 2);
                for i = 1:numel(array)
                    tableData{i, 1} = array{i}.name;
                    tableData{i, 2} = num2str(array{i}.total_time);
                    tableData{i,3} = array{i}.level;
                end
                app.UITable.Data = tableData;
                app.UITable.ColumnName{2} = "Total Time";
                end
            end
            

        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged(app, event)
            value = app.VolumeSlider.Value;
            app.VolumeSlider.Value = round(value / 10) * 10;
            disp(app.VolumeSlider.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.Background_image = uiimage(app.UIFigure);
            app.Background_image.ImageSource = fullfile(pathToMLAPP, 'Images', 'Background', '1_Mountain_valley.png');
            app.GB_1 = uiimage(app.UIFigure);
            app.GB_1.ImageSource = fullfile(pathToMLAPP, 'Images', 'Simple Grass Block.png');
            app.GB_2 = uiimage(app.UIFigure);
            app.GB_2.ImageSource = fullfile(pathToMLAPP, 'Images', 'Simple Grass Block.png');
            app.GB_3 = uiimage(app.UIFigure);
            app.GB_3.ImageSource = fullfile(pathToMLAPP, 'Images', 'Simple Grass Block.png');
            app.GB_4 = uiimage(app.UIFigure);
            app.GB_4.ImageSource = fullfile(pathToMLAPP, 'Images', 'Simple Grass Block.png');
            app.GB_5 = uiimage(app.UIFigure);
            app.GB_5.ImageSource = fullfile(pathToMLAPP, 'Images', 'Simple Grass Block.png');
            app.GB_6 = uiimage(app.UIFigure);
            app.GB_6.ImageSource = fullfile(pathToMLAPP, 'Images', 'Simple Grass Block.png');
            app.GB_7 = uiimage(app.UIFigure);
            app.GB_7.ImageSource = fullfile(pathToMLAPP, 'Images', 'Simple Grass Block.png');
            app.GB_8 = uiimage(app.UIFigure);
            app.GB_8.ImageSource = fullfile(pathToMLAPP, 'Images', 'Simple Grass Block.png');
            app.GB_9 = uiimage(app.UIFigure);
            app.GB_9.ImageSource = fullfile(pathToMLAPP, 'Images', 'Simple Grass Block.png');
            app.GB_10 = uiimage(app.UIFigure);
            app.GB_10.ImageSource = fullfile(pathToMLAPP, 'Images', 'Simple Grass Block.png');
            app.GB_11 = uiimage(app.UIFigure);
            app.GB_11.ImageSource = fullfile(pathToMLAPP, 'Images', 'Simple Grass Block.png');
            app.Timer = uilabel(app.UIFigure);
            app.Timer.Text = '0.000';
            app.pause_Image = uiimage(app.UIFigure);
            app.pause_Image.ImageClickedFcn = createCallbackFcn(app, @Pause_Menu, true);
            app.pause_Image.Visible = 'off';
            app.pause_Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'Pause_Play', 'Pause_image.png');
            app.pause_Image_2 = uiimage(app.UIFigure);
            app.pause_Image_2.ImageClickedFcn = createCallbackFcn(app, @add_, true);
            app.pause_Image_2.ImageSource = fullfile(pathToMLAPP, 'Images', 'Pause_Play', 'Pause_image.png');
            app.levelLabel = uilabel(app.UIFigure);
            app.levelLabel.Text = 'Level: 0';
            app.TeleportLabel = uilabel(app.UIFigure);
            app.TeleportLabel.Text = 'TELEPORT';
            app.hp_text = uilabel(app.UIFigure);
            app.hp_text.Text = '100';
            app.Health_bar = uilabel(app.UIFigure);
            app.Health_bar.Text = '';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.homeTab = uitab(app.TabGroup);
            app.homeTab.Title = 'Main Menu';
            app.StartGameButton = uibutton(app.homeTab, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @Start_game, true);
            app.StartGameButton.Text = 'Start Game';
            app.Select_backgroundImg = uiimage(app.homeTab);
            app.Select_backgroundImg.ImageSource = fullfile(pathToMLAPP, 'Images', 'Background', '1_Mountain_valley.png');
            app.prevBackground = uibutton(app.homeTab, 'push');
            app.prevBackground.ButtonPushedFcn = createCallbackFcn(app, @prevBackgroundButtonPushed, true);
            app.prevBackground.Text = '<<';
            app.nextBackground = uibutton(app.homeTab, 'push');
            app.nextBackground.ButtonPushedFcn = createCallbackFcn(app, @nextBackgroundButtonPushed, true);
            app.nextBackground.Text = '>>';
            app.NameInput = uitextarea(app.homeTab);
            app.NameInput.ValueChangedFcn = createCallbackFcn(app, @NameInputValueChanged, true);
            app.NameInput.Placeholder = 'Enter Name';
            app.Image = uiimage(app.homeTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'Cyborg', 'Cyborg_run', 'run_1.png');
            app.UITable = uitable(app.homeTab);
            app.UITable.ColumnName = {'Name'; 'Time'; 'Level'};
            app.UITable.RowName = {};
            app.UITable.Multiselect = 'off';
            app.Switch = uiswitch(app.homeTab, 'slider');
            app.Switch.Items = {'Endless', 'Normal'};
            app.Switch.ItemsData = {'0', '1'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @leaderBoard_switch, true);
            app.Switch.Value = '0';
            app.EndlessModeSwitchLabel = uilabel(app.homeTab);
            app.EndlessModeSwitchLabel.Text = 'Endless Mode';
            app.EndlessModeSwitch = uiswitch(app.homeTab, 'slider');
            app.EndlessModeSwitch.Items = {'off', 'on'};
            app.EndlessModeSwitch.ItemsData = {'0', '1'};
            app.EndlessModeSwitch.ValueChangedFcn = createCallbackFcn(app, @EndlessModeSwitchValueChanged, true);
            app.EndlessModeSwitch.Value = '0';
            app.Game_Name = uilabel(app.homeTab);
            app.Game_Name.Text = 'SPIKE RUNNER';
            app.VolumeSliderLabel = uilabel(app.homeTab);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.homeTab);
            app.VolumeSlider.MajorTickLabels = {''};
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.VolumeSlider.MinorTicks = [];
            app.ControlsTab = uitab(app.TabGroup);
            app.ControlsTab.Title = 'Controls';
            app.JUMPDOUBLEJUMPLabel = uilabel(app.ControlsTab);
            app.JUMPDOUBLEJUMPLabel.Text = 'JUMP/DOUBLE JUMP';
            app.SPACELabel = uilabel(app.ControlsTab);
            app.SPACELabel.Text = 'SPACE';
            app.LEFTLabel = uilabel(app.ControlsTab);
            app.LEFTLabel.Text = 'LEFT';
            app.RIGHTLabel = uilabel(app.ControlsTab);
            app.RIGHTLabel.Text = 'RIGHT';
            app.ALabel = uilabel(app.ControlsTab);
            app.ALabel.Text = 'A';
            app.DLabel = uilabel(app.ControlsTab);
            app.DLabel.Text = 'D';
            app.TELEPORTLabel = uilabel(app.ControlsTab);
            app.TELEPORTLabel.Text = 'TELEPORT';
            app.LEFTCLICKLabel = uilabel(app.ControlsTab);
            app.LEFTCLICKLabel.Text = 'LEFT CLICK';
            app.CreditsTab = uitab(app.TabGroup);
            app.CreditsTab.Title = 'Credits';
            app.Hyperlink = uihyperlink(app.CreditsTab);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.URL = 'https://free-game-assets.itch.io/free-3-cyberpunk-sprites-pixel-art';
            app.Hyperlink.Text = {'Player - https://free-game-assets.itch.io/free-3-cyberpunk-sprites-pixel-art'; ''};
            app.PIXELARTLabel = uilabel(app.CreditsTab);
            app.PIXELARTLabel.Text = 'PIXEL ART';
            app.Hyperlink2 = uihyperlink(app.CreditsTab);
            app.Hyperlink2.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink2.URL = 'https://craftpix.net/freebies/free-nature-backgrounds-pixel-art/';
            app.Hyperlink2.Text = 'Background - https://craftpix.net/freebies/free-nature-backgrounds-pixel-art/';
            app.Hyperlink3 = uihyperlink(app.CreditsTab);
            app.Hyperlink3.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink3.URL = 'https://craftpix.net/freebies/free-simple-platformer-game-kit-pixel-art/';
            app.Hyperlink3.Text = ['Spikes - https://craftpix.net/freebies/free-simple-platformer-game-kit-pixel-art/' newline ''];
            app.BackgroundMusicLabel = uilabel(app.CreditsTab);
            app.BackgroundMusicLabel.Text = 'Background Music';
            app.suno_ai_Link = uihyperlink(app.CreditsTab);
            app.suno_ai_Link.Text = {'Background music - https://suno.com/'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project_Game

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end